classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        EntrenamientoredTab          matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        EntrenarredButton            matlab.ui.control.Button
        GridLayout3                  matlab.ui.container.GridLayout
        ComenzarentrenamientoButton  matlab.ui.control.Button
        GridLayout4                  matlab.ui.container.GridLayout
        TextAreaLabel                matlab.ui.control.Label
        NumMovsLabel                 matlab.ui.control.TextArea
        TextArea2Label               matlab.ui.control.Label
        NombrearchivoLabel           matlab.ui.control.TextArea
        RESETButton                  matlab.ui.control.Button
        Mnimo1Mximo5Label            matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        numeroMovsEditFieldLabel     matlab.ui.control.Label
        numeroMovsEditField          matlab.ui.control.EditField
        TextArea_2Label              matlab.ui.control.Label
        NombrearchivodatosEditField  matlab.ui.control.TextArea
        IntroduceunnombreLabel       matlab.ui.control.Label
        IntroduceunnmeroLabel        matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        BuscarButton                 matlab.ui.control.Button
        AceptarButton                matlab.ui.control.Button
        AadirButton                  matlab.ui.control.Button
        CREARREDCONMOVIMIENTOSPROPIOSLabel  matlab.ui.control.Label
        ReconocimientoaccionesTab    matlab.ui.container.Tab
        ComenzarcapturadeaccionesButton  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        numArchivos % numero de archivos para crear la red
        nombresArchivos % nombre de los archivos para crear la red
        popUpEntrenamientoWindow %pop up para comenzar el entrenamiento
    end
    
    methods (Access = private)
        
        function setGlobalNumArchivos(app,val)
           app.numArchivos = val;
        end
        
        function r = getGlobalNumArchivos(app)
            r = app.numArchivos;
        end
        
        function setNombresArchivos(app)
           app.nombresArchivos = {};
        end
        function insertNombresArchivos(app,val,i)
            app.nombresArchivos(1,i)=val;
        end
        
        
        function setValuesReadyPart2(app)
            app.NumMovsLabel.Enable=0;
            app.numeroMovsEditField.Enable=0;
            app.AceptarButton.Enable=0;
            app.IntroduceunnmeroLabel.FontColor="0.47,0.67,0.19";
            app.IntroduceunnmeroLabel.Text="Valor válido";
            app.BuscarButton.Enable=1;
            app.NombrearchivoLabel.Enable=1;
            app.NombrearchivodatosEditField.Enable=1;
            app.IntroduceunnombreLabel.Enable=1;
            app.RESETButton.Enable=1;
            setNombresArchivos(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EntrenarredButton
        function EntrenarredButtonPushed(app, event)
            entrenamientoRed;
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
             %Busca el archivo en la carpeta MobileSensorData
            if exist("MobileSensorData/"+app.NombrearchivodatosEditField.Value+".mat", "file")
                app.IntroduceunnombreLabel.Text = "Archivo encontrado";
                app.IntroduceunnombreLabel.FontColor = "0.47,0.67,0.19";
                app.AadirButton.Enable=1;
            else
                app.IntroduceunnombreLabel.Text = "Archivo NO encontrado";
                app.IntroduceunnombreLabel.FontColor = "1.00,0.00,0.00";
                app.AadirButton.Enable=0;
            end
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)

           setGlobalNumArchivos(app, int16(app.numeroMovsEditField.Value)-48);
           %de 1 a 5 movimientos
            if (0 < app.numArchivos) && (app.numArchivos < 6)
                setValuesReadyPart2(app);
            else
                app.IntroduceunnmeroLabel.Text="Valor no válido";
                app.IntroduceunnmeroLabel.FontColor="0.64,0.08,0.18";
            end
        end

        % Button pushed function: AadirButton
        function AadirButtonPushed(app, event)
            app.numArchivos = getGlobalNumArchivos(app);
            [~,n] = size(app.nombresArchivos);
            if n < app.numArchivos
                insertNombresArchivos(app, app.NombrearchivodatosEditField.Value, n+1);
                app.IntroduceunnombreLabel.Text="Archivo añadido";
            end
            [~,n] = size(app.nombresArchivos);
            if n >= app.numArchivos %ya se han insertado los nombres de todos los archivos
                app.AadirButton.Enable=0;
                app.BuscarButton.Enable=0;
                app.ComenzarentrenamientoButton.Enable=1;
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.numeroMovsEditField.Value="";
            app.NumMovsLabel.Enable=1;
            app.numeroMovsEditField.Enable=1;
            app.AceptarButton.Enable=1;
            app.IntroduceunnmeroLabel.FontColor="0.00,0.00,0.00";
            app.IntroduceunnmeroLabel.Text="Introduce un número";
            app.BuscarButton.Enable=0;
            app.NombrearchivoLabel.Enable=0;
            app.NombrearchivodatosEditField.Enable=0;
            app.IntroduceunnombreLabel.Enable=0;
            app.ComenzarentrenamientoButton.Enable=0;
            app.NombrearchivodatosEditField.Value="";
            app.IntroduceunnombreLabel.Text="Introduce un nombre";
            app.IntroduceunnombreLabel.FontColor="0.00,0.00,0.00";
            app.RESETButton.Enable=0;
        end

        % Button pushed function: ComenzarentrenamientoButton
        function ComenzarentrenamientoButtonPushed(app, event)
            [~,n] = size(app.nombresArchivos);
            textonombres =app.nombresArchivos(1,1);
            for i = 2:n
               textonombres = textonombres + ", ";
               textonombres = textonombres+ app.nombresArchivos(1,i);
            end
            msg = "Se creará una red a partir de los archivos "+ textonombres + ". Las acciones se nombrarán como el nombre del archivo. Si desea renombrar las acciones, renombre el archivo y súbalos de nuevo. ¿Quiere continuar? En caso de pulsar NO, se borrarán los archivos introducidos";
            fig = uifigure;
           title = 'Comenzar a entrenar';
           selection = uiconfirm(fig,msg,title,'CloseFcn',@(h,e) close(fig));
            if selection(1,1) == "OK"
               %se realizaría el entrenamiento
               
            else
                app.NombrearchivodatosEditField.Value="";
                app.IntroduceunnombreLabel.Text="Introduce un nombre";
                app.IntroduceunnombreLabel.FontColor="0.00,0.00,0.00";
                app.ComenzarentrenamientoButton.Enable=0;
                setValuesReadyPart2(app);
            end
        end

        % Button pushed function: ComenzarcapturadeaccionesButton
        function ComenzarcapturadeaccionesButtonPushed(app, event)
            ActividadLSTMTFG;
        end

        % Size changed function: ReconocimientoaccionesTab
        function ReconocimientoaccionesTabSizeChanged(app, event)
            app.ReconocimientoaccionesTab.Position;
            InicioLSTMTFG;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenamientoredTab = uitab(app.TabGroup);
            app.EntrenamientoredTab.Title = 'Entrenamiento red';
            app.GridLayout = uigridlayout(app.EntrenamientoredTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EntrenarredButton = uibutton(app.GridLayout2, 'push');
            app.EntrenarredButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarredButtonPushed, true);
            app.EntrenarredButton.Text = 'Entrenar red';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ComenzarentrenamientoButton = uibutton(app.GridLayout3, 'push');
            app.ComenzarentrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarentrenamientoButtonPushed, true);
            app.ComenzarentrenamientoButton.Enable = 'off';
            app.ComenzarentrenamientoButton.Text = 'Comenzar entrenamiento';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TextAreaLabel = uilabel(app.GridLayout4);
            app.TextAreaLabel.Text = 'Text Area';
            app.NumMovsLabel = uitextarea(app.GridLayout4);
            app.NumMovsLabel.Value = {'Número de movimientos:'};
            app.TextArea2Label = uilabel(app.GridLayout4);
            app.TextArea2Label.Text = 'Text Area2';
            app.NombrearchivoLabel = uitextarea(app.GridLayout4);
            app.NombrearchivoLabel.Enable = 'off';
            app.NombrearchivoLabel.Value = {'Nombre de archivo:'};
            app.RESETButton = uibutton(app.GridLayout4, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.Mnimo1Mximo5Label = uilabel(app.GridLayout4);
            app.Mnimo1Mximo5Label.Text = 'Mínimo 1 Máximo 5';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.numeroMovsEditFieldLabel = uilabel(app.GridLayout5);
            app.numeroMovsEditFieldLabel.Text = 'numeroMovs';
            app.numeroMovsEditField = uieditfield(app.GridLayout5, 'text');
            app.TextArea_2Label = uilabel(app.GridLayout5);
            app.TextArea_2Label.Enable = 'off';
            app.TextArea_2Label.Text = '';
            app.NombrearchivodatosEditField = uitextarea(app.GridLayout5);
            app.NombrearchivodatosEditField.Enable = 'off';
            app.IntroduceunnombreLabel = uilabel(app.GridLayout5);
            app.IntroduceunnombreLabel.Enable = 'off';
            app.IntroduceunnombreLabel.Text = 'Introduce un nombre';
            app.IntroduceunnmeroLabel = uilabel(app.GridLayout5);
            app.IntroduceunnmeroLabel.Text = 'Introduce un número';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.BuscarButton = uibutton(app.GridLayout6, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Enable = 'off';
            app.BuscarButton.Text = 'Buscar';
            app.AceptarButton = uibutton(app.GridLayout6, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.AadirButton = uibutton(app.GridLayout6, 'push');
            app.AadirButton.ButtonPushedFcn = createCallbackFcn(app, @AadirButtonPushed, true);
            app.AadirButton.Enable = 'off';
            app.AadirButton.Text = 'Añadir';
            app.CREARREDCONMOVIMIENTOSPROPIOSLabel = uilabel(app.GridLayout3);
            app.CREARREDCONMOVIMIENTOSPROPIOSLabel.Text = 'CREAR RED CON MOVIMIENTOS PROPIOS';
            app.ReconocimientoaccionesTab = uitab(app.TabGroup);
            app.ReconocimientoaccionesTab.SizeChangedFcn = createCallbackFcn(app, @ReconocimientoaccionesTabSizeChanged, true);
            app.ReconocimientoaccionesTab.Title = 'Reconocimiento acciones';
            app.ComenzarcapturadeaccionesButton = uibutton(app.ReconocimientoaccionesTab, 'push');
            app.ComenzarcapturadeaccionesButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarcapturadeaccionesButtonPushed, true);
            app.ComenzarcapturadeaccionesButton.Text = 'Comenzar captura de acciones';
            app.Label = uilabel(app.ReconocimientoaccionesTab);
            app.Label.Text = 'Antes de comenzar la captura de acciones asegurese de activar los sensores en el dispositivo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end